classdef index < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UploadVideosButton    matlab.ui.control.Button
        AnalyzeResultsButton  matlab.ui.control.Button
        UITable               matlab.ui.control.Table
    end

    
    properties (Access = private)
        arrayFiles = strings % Description
    end
    
    methods (Access = private)
        
        function [output] = func(app)
            %% Create table headers
            output = ["Videos","Time (h)","Initial Size (um^2)","Final Size (um^2)","Final Size Rank","Average Growth Rate","Avg Growth Rate Rank"];
            %% Define set variables
            imageSize = 496;
            pixel2real = 0.58*0.58;
            %% Load neural network (in same directory)
            load('net_all_combined_all.mat', 'net');
            %% Create empty variables
            %count = 0;
            files = [];
            %% Receive input on how many videos being processed and initialize
            numVideos = input("How many videos are you processing? ");
            rankGrowth = zeros(numVideos, 2);
            rankSize = zeros(numVideos, 2);
            %% Ask for filepath
            % EX: /Users/wchang/Desktop/VIP/Video/4981.avi
            for i=1:numVideos
                fprintf("Video %.0f\n", i);
                in = input("What is the filepath? ", 's');
                files = [files; string(in)];
                rankGrowth(i) = i;
                rankSize(i) = i;
            end
            %% For each video...
            for i=1:numVideos
                file = files(i);
                [filepath,name,ext] = fileparts(file);
                % Make a directory for original images
                mkdir(name);
                % Make a directory for predicted images
                mkdir(strcat(name, '_new'));
                vidObj = VideoReader(file);
                time_to_remember = vidObj.CurrentTime;
                numFrames = 0;
                path = strcat(filepath,'/',name);
                % Get count of total number of images
                while hasFrame(vidObj)
                    readFrame(vidObj);
                    numFrames = numFrames + 1;
                end
                totalTime = vidObj.CurrentTime;
                pixelArray = zeros(1,numFrames);
                timeArray = zeros(1,numFrames);
                vidObj.CurrentTime = time_to_remember;
                %% For each image...
                for k=1:numFrames
                    % Convert image to correct size and type for CNN
                    Image = readFrame(vidObj);
                    Image = rgb2gray(Image);
                    Image = imresize(Image, [imageSize imageSize]);
                    timeArray(k) = timeCalc(Image);
                    if(k<10)
                        filename=strcat(name,'/','00',num2str(k),'_',name,'.png');
                    elseif(k>=10 && k<=99)
                        filename=strcat(name,'/','0',num2str(k),'_',name,'.png');
                    else
                        filename=strcat(name,'/',num2str(k),'_',name,'.png');
                    end
                    strcat(filepath,'/',name,'/',filename);
                    
                    imwrite(Image,filename,'png');
                    
                    [C,scores] = semanticseg(Image, net);
                    pixelArray(k) = sum(C(:) == "Embryo")*pixel2real;
                    Image = labeloverlay(Image, C);
                    if(k<10)
                        filename=strcat(name,'_new','/','00',num2str(k),'_',name,'.png');
                    elseif(k>=10 && k<=99)
                        filename=strcat(name,'_new','/','0',num2str(k),'_',name,'.png');
                    else
                        filename=strcat(name,'_new','/',num2str(k),'_',name,'.png');
                    end
                    strcat(filepath,'_new','/',name,'/',filename);
                    
                    imwrite(Image,filename,'png');
                end
                
                disp(timeArray);
                baseTime = timeArray(1);
                for k=1:numFrames
                    timeArray(k) = timeArray(k) - baseTime;
                end
                
                rankSize(i,2) = pixelArray(numFrames);
                P = linearRegression(pixelArray, timeArray);
                rankGrowth(i,2) = P(1);
                name = convertCharsToStrings(name);
                array = [name timeArray(numFrames) pixelArray(1) pixelArray(numFrames) 1 rankGrowth(i,2) 1];
                output = [output; array];
            end
            
            %% If there are more than 1 videos...
            if(numVideos ~= 1)
                %% Insertion sort for the final size
                for i=2:numVideos
                    key = rankSize(i, 2);
                    m = i - 1;
                    
                    while(m >= 1 && rankSize(m, 2) > key)
                        rankSize(m+1, 2) = rankSize(m, 2);
                        rankSize(m+1, 1) = rankSize(m, 1);
                        m = m - 1;
                    end
                    rankSize(m+1, 2) = key;
                    rankSize(m+1, 1) = i;
                end
                %% Insertion sort for the growth rate
                for i=2:numVideos
                    key = rankGrowth(i, 2);
                    m = i - 1;
                    
                    while(m >= 1 && rankGrowth(m, 2) > key)
                        rankGrowth(m+1, 2) = rankGrowth(m, 2);
                        rankGrowth(m+1, 1) = rankGrowth(m, 1);
                        m = m - 1;
                    end
                    rankGrowth(m+1, 2) = key;
                    rankGrowth(m+1, 1) = i;
                end
                %% Insert rankings into the matrix
                for i=1:numVideos
                    output(1+rankSize(i), 5) = numVideos + 1 - i;
                    output(1+rankGrowth(i), 7) = numVideos + 1 - i;
                end
            end
            %% Print results in csv file
            writematrix(output, 'embryo_results.csv');
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
        end

        % Button pushed function: UploadVideosButton
        function UploadVideosButtonPushed(app, event)
            [file,path,indx] = uigetfile( ...
            {'*.webm','WEBM (*.webm)'; ...
                '*.mp4;*.m4p;*.m4v','MP4 (*.mp4, *.m4p, *.m4v)'; ...
                '*.mpg;*.mp2;*.mpeg;*.mpe;*.mpv','MPG (*.mpg, *.mp2, *.mpeg, *.mpe, *.mpv)'; ...
                '*.ogg','OGG (*.ogg)'; ...
                '*.avi','AVI (*.avi)'; ...
                '*.wmv','WMV (*.wmv)'; ...
                '*.mov;*.qt','QuickTime (*.mov, *.qt)'; ...
                '*.flv;*.swf','Flash (*.flv, *.swf)'}, ...
                'Select Multiple Files by Holding Shift or Ctrl', 'MultiSelect', 'on');
            
            s = length(file);


            for n = 1:s
          
                
                app.arrayFiles = app.arrayFiles + file(n) + newline;
                
            end
            
            newStr = splitlines(app.arrayFiles);
            
            cellFiles = cellstr(newStr);
            
            app.UITable.Data = cellFiles;
            
        end

        % Button pushed function: AnalyzeResultsButton
        function AnalyzeResultsButtonPushed(app, event)
                visualizeResults;
                delete(app);
        end

        % Callback function
        function CellEdit(app, event)
                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UploadVideosButton = uibutton(app.UIFigure, 'push');
            app.UploadVideosButton.ButtonPushedFcn = createCallbackFcn(app, @UploadVideosButtonPushed, true);
            app.UploadVideosButton.Text = 'Upload Video(s)';
            app.AnalyzeResultsButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeResultsButtonPushed, true);
            app.AnalyzeResultsButton.Text = 'Analyze Results';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'File Name'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = index

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end