classdef indexWithTableAndRemovalButtonWorkingV5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UploadVideosButton        matlab.ui.control.Button
        AnalyzeResultsButton      matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        RemoveFilesPanel          matlab.ui.container.Panel
        RemoveSelectedFileButton  matlab.ui.control.Button
        DropDownLabel             matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        Panel                     matlab.ui.container.Panel
        AreyousureLabel           matlab.ui.control.Label
        yesButton                 matlab.ui.control.Button
        noButton                  matlab.ui.control.Button
        closeappButton            matlab.ui.control.Button
        UITable2                  matlab.ui.control.Table
    end

    
    properties (Access = private)
        arrayFiles = strings % Names of files
        dropDownSelection % Current drop down selection
        file % gets the file names from thingy
        splitArrayFiles % holds the value of arrayFiles after its been split
        counter = 0; % tells whether an item has been added previously
        path % gets the path names from thingy
        splitArrayPath % Description
        arrayPath % Description
        listCounter = 0; % Description
    end
    
    methods (Access = private)
        
        function [output] = func(app)
            %% Create table headers
            output = ["Videos","Time (h)","Initial Size (um^2)","Final Size (um^2)","Final Size Rank","Average Growth Rate","Avg Growth Rate Rank"];
            %% Define set variables
            imageSize = 496;
            pixel2real = 0.58*0.58;
            %% Load neural network (in same directory)
            load('net_all_combined_all.mat', 'net');
            %% Create empty variables
            %count = 0;
            files = [];
            %% Receive input on how many videos being processed and initialize
            numVideos = input("How many videos are you processing? ");
            rankGrowth = zeros(numVideos, 2);
            rankSize = zeros(numVideos, 2);
            %% Ask for filepath
            % EX: /Users/wchang/Desktop/VIP/Video/4981.avi
            for i=1:numVideos
                fprintf("Video %.0f\n", i);
                in = input("What is the filepath? ", 's');
                files = [files; string(in)];
                rankGrowth(i) = i;
                rankSize(i) = i;
            end
            %% For each video...
            for i=1:numVideos
                file = files(i);
                [filepath,name,ext] = fileparts(file);
                % Make a directory for original images
                mkdir(name);
                % Make a directory for predicted images
                mkdir(strcat(name, '_new'));
                vidObj = VideoReader(file);
                time_to_remember = vidObj.CurrentTime;
                numFrames = 0;
                path = strcat(filepath,'/',name);
                % Get count of total number of images
                while hasFrame(vidObj)
                    readFrame(vidObj);
                    numFrames = numFrames + 1;
                end
                totalTime = vidObj.CurrentTime;
                pixelArray = zeros(1,numFrames);
                timeArray = zeros(1,numFrames);
                vidObj.CurrentTime = time_to_remember;
                %% For each image...
                for k=1:numFrames
                    % Convert image to correct size and type for CNN
                    Image = readFrame(vidObj);
                    Image = rgb2gray(Image);
                    Image = imresize(Image, [imageSize imageSize]);
                    timeArray(k) = timeCalc(Image);
                    if(k<10)
                        filename=strcat(name,'/','00',num2str(k),'_',name,'.png');
                    elseif(k>=10 && k<=99)
                        filename=strcat(name,'/','0',num2str(k),'_',name,'.png');
                    else
                        filename=strcat(name,'/',num2str(k),'_',name,'.png');
                    end
                    strcat(filepath,'/',name,'/',filename);
                    
                    imwrite(Image,filename,'png');
                    
                    [C,scores] = semanticseg(Image, net);
                    pixelArray(k) = sum(C(:) == "Embryo")*pixel2real;
                    Image = labeloverlay(Image, C);
                    if(k<10)
                        filename=strcat(name,'_new','/','00',num2str(k),'_',name,'.png');
                    elseif(k>=10 && k<=99)
                        filename=strcat(name,'_new','/','0',num2str(k),'_',name,'.png');
                    else
                        filename=strcat(name,'_new','/',num2str(k),'_',name,'.png');
                    end
                    strcat(filepath,'_new','/',name,'/',filename);
                    
                    imwrite(Image,filename,'png');
                end
                
                disp(timeArray);
                baseTime = timeArray(1);
                for k=1:numFrames
                    timeArray(k) = timeArray(k) - baseTime;
                end
                
                rankSize(i,2) = pixelArray(numFrames);
                P = linearRegression(pixelArray, timeArray);
                rankGrowth(i,2) = P(1);
                name = convertCharsToStrings(name);
                array = [name timeArray(numFrames) pixelArray(1) pixelArray(numFrames) 1 rankGrowth(i,2) 1];
                output = [output; array];
            end
            
            %% If there are more than 1 videos...
            if(numVideos ~= 1)
                %% Insertion sort for the final size
                for i=2:numVideos
                    key = rankSize(i, 2);
                    m = i - 1;
                    
                    while(m >= 1 && rankSize(m, 2) > key)
                        rankSize(m+1, 2) = rankSize(m, 2);
                        rankSize(m+1, 1) = rankSize(m, 1);
                        m = m - 1;
                    end
                    rankSize(m+1, 2) = key;
                    rankSize(m+1, 1) = i;
                end
                %% Insertion sort for the growth rate
                for i=2:numVideos
                    key = rankGrowth(i, 2);
                    m = i - 1;
                    
                    while(m >= 1 && rankGrowth(m, 2) > key)
                        rankGrowth(m+1, 2) = rankGrowth(m, 2);
                        rankGrowth(m+1, 1) = rankGrowth(m, 1);
                        m = m - 1;
                    end
                    rankGrowth(m+1, 2) = key;
                    rankGrowth(m+1, 1) = i;
                end
                %% Insert rankings into the matrix
                for i=1:numVideos
                    output(1+rankSize(i), 5) = numVideos + 1 - i;
                    output(1+rankGrowth(i), 7) = numVideos + 1 - i;
                end
            end
            %% Print results in csv file
            writematrix(output, 'embryo_results.csv');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.Panel.Visible = 'off';
        end

        % Button pushed function: UploadVideosButton
        function UploadVideosButtonPushed(app, event)
            
            %only problem is that if there are two files of the same name
            %the first one found is deleted, not necessarily the one
            %selected
            
            [file,path,indx] = uigetfile( ...
            {'*.avi','AVI (*.avi)'; ...
            '*.webm','WEBM (*.webm)'; ...
                '*.mp4;*.m4p;*.m4v','MP4 (*.mp4, *.m4p, *.m4v)'; ...
                '*.mpg;*.mp2;*.mpeg;*.mpe;*.mpv','MPG (*.mpg, *.mp2, *.mpeg, *.mpe, *.mpv)'; ...
                '*.ogg','OGG (*.ogg)'; ...
                '*.wmv','WMV (*.wmv)'; ...
                '*.mov;*.qt','QuickTime (*.mov, *.qt)'; ...
                '*.flv;*.swf','Flash (*.flv, *.swf)'}, ...
                'Select Multiple Files by Holding Shift or Ctrl', 'MultiSelect', 'on');


            %file name addition code


            if isequal(file,0)
                disp('User selected Cancel');
                return
            else
            
                app.file = cellstr(file);
                app.path = cellstr(path);
                
                s = length(app.file);
                
                %adds normally if nothing has been deleted
                if(app.counter ~= 1 || isempty(app.splitArrayFiles))
                
                    app.arrayFiles = strings;
                    app.arrayPath = strings;
                    
                    for n = 1:s
                        app.listCounter = app.listCounter + 1;
                        %now works
                        app.arrayFiles = app.arrayFiles + string(app.file(n)) + '(' + app.listCounter + ')' + newline;
                        app.arrayPath = app.arrayPath + app.path + string(app.file(n)) + newline;
                        
                    end
                    
                    %splits the array into one column then 
                    app.splitArrayFiles = splitlines(app.arrayFiles);
                    app.splitArrayPath = splitlines(app.arrayPath);
                    
                    %deletes the last item in splitArrayFiles because this is an
                    %extra newline
                    
                    app.splitArrayFiles(end) = [];
                    app.splitArrayPath(end) = [];
                    
                    %converts into cell array from string array
                    
                    cellFiles = cellstr(app.splitArrayFiles);
                    cellPath = cellstr(app.splitArrayPath);
                    
                    %uses splitArrayFiles for the old data if counter = 1, as 
                    %that's what changes upon deletion
                
                else
                    app.arrayFiles = strings;
                    app.arrayPath = strings;
                    
                    for n = 1:s
                        app.listCounter = app.listCounter + 1;
                        %now works
                        app.arrayFiles = app.arrayFiles + string(app.file(n)) + '(' + app.listCounter + ')' + newline;
                        app.arrayPath = app.arrayPath + app.path + string(app.file(n)) + newline;
                    end
                    
                    %deletes the last item in splitArrayFiles because this is an
                    %extra newline
                    
                    split = splitlines(app.arrayFiles);
                    splitPath = splitlines(app.arrayPath);
                
                    split(end) = [];
                    splitPath(end) = [];
                    
                    app.splitArrayFiles = [app.splitArrayFiles; split];
                    app.splitArrayPath = [app.splitArrayPath; splitPath];
                    
                    cellFiles = cellstr(app.splitArrayFiles);
                    cellPath = cellstr(app.splitArrayPath);
    
                end
                
                %changes the value in the table
                app.UITable.Data = cellFiles;
                app.UITable2.Data = cellPath;
                
                %changes the values in the drop down
                app.DropDown.Items = cellFiles;
                
                %cahnges counter so program knows that something was added
                
                app.counter = 1;
               
            end


        end

        % Button pushed function: AnalyzeResultsButton
        function AnalyzeResultsButtonPushed(app, event)
                visualizeResults;
                delete(app);
        end

        % Callback function
        function CellEdit(app, event)
                
        end

        % Callback function
        function DeleteFileMaybe(app, event)
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
       
            
        end

        % Button pushed function: RemoveSelectedFileButton
        function DeleteFileSelection(app, event)
            app.Panel.Visible = 'on';
        end

        % Close request function: UIFigure
        function AreYouSurePressed(app, event)
   
            
        end

        % Button pushed function: yesButton
        function yesRemovalButtonPressed(app, event)
           
            %finds the index of the selected item
            x = 0;
            for i= 1:length(app.splitArrayPath)
                if (app.splitArrayFiles(i) == app.DropDown.Value)
                    x = i;
                    break
                end
            end
            
            %deletes the row found
            
            app.splitArrayFiles(x,:) = [];
            app.splitArrayPath(x,:) = [];
            
            %converts into cell array from string array
            
            cellFiles = cellstr(app.splitArrayFiles);
            cellPath = cellstr(app.splitArrayPath);
            
            %changes the value in the table
            app.UITable.Data = cellFiles;
            app.UITable2.Data = cellPath;
            
            %changes the values in the drop down
            app.DropDown.Items = cellFiles;
            
            app.Panel.Visible = 'off';
        end

        % Button pushed function: noButton
        function noRemovalButtonPressed(app, event)
            app.Panel.Visible = 'off';
        end

        % Button pushed function: closeappButton
        function closeappButtonPushed(app, event)
             % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @AreYouSurePressed, true);
            app.UploadVideosButton = uibutton(app.UIFigure, 'push');
            app.UploadVideosButton.ButtonPushedFcn = createCallbackFcn(app, @UploadVideosButtonPushed, true);
            app.UploadVideosButton.Text = 'Upload Video(s)';
            app.AnalyzeResultsButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeResultsButtonPushed, true);
            app.AnalyzeResultsButton.Text = 'Analyze Results';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'File Name'};
            app.UITable.RowName = {};
            app.RemoveFilesPanel = uipanel(app.UIFigure);
            app.RemoveFilesPanel.Title = 'Remove Files';
            app.RemoveSelectedFileButton = uibutton(app.RemoveFilesPanel, 'push');
            app.RemoveSelectedFileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFileSelection, true);
            app.RemoveSelectedFileButton.Text = 'Remove Selected File';
            app.DropDownLabel = uilabel(app.RemoveFilesPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.RemoveFilesPanel);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.Panel = uipanel(app.UIFigure);
            app.AreyousureLabel = uilabel(app.Panel);
            app.AreyousureLabel.Text = 'Are you sure?';
            app.yesButton = uibutton(app.Panel, 'push');
            app.yesButton.ButtonPushedFcn = createCallbackFcn(app, @yesRemovalButtonPressed, true);
            app.yesButton.Text = 'yes';
            app.noButton = uibutton(app.Panel, 'push');
            app.noButton.ButtonPushedFcn = createCallbackFcn(app, @noRemovalButtonPressed, true);
            app.noButton.Text = 'no';
            app.closeappButton = uibutton(app.UIFigure, 'push');
            app.closeappButton.ButtonPushedFcn = createCallbackFcn(app, @closeappButtonPushed, true);
            app.closeappButton.Text = 'close app';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'File Path'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = indexWithTableAndRemovalButtonWorkingV5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end